classdef app1_copy < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        UserDropDownLabel        matlab.ui.control.Label
        UserDropDown             matlab.ui.control.DropDown
        ExperimentDropDownLabel  matlab.ui.control.Label
        ExperimentDropDown       matlab.ui.control.DropDown
        DateandtimeLabel         matlab.ui.control.Label
        MouseIDDropDownLabel     matlab.ui.control.Label
        MouseIDDropDown          matlab.ui.control.DropDown
        DobLabel                 matlab.ui.control.Label
        SexLabel                 matlab.ui.control.Label
        GenotypeLabel            matlab.ui.control.Label
        GroupLabel               matlab.ui.control.Label
        UITable                  matlab.ui.control.Table
        RunExperimentButton      matlab.ui.control.StateButton
        AgeLabel                 matlab.ui.control.Label
        saveLabel                matlab.ui.control.Label
    end

    
    properties (Access = private)
        group
        dob
        sex
        genotype
        datetimenow 
        age
        basefolder
        mouseID
        name
        exp
        savedir
        savefile
        metadata
        data
    end
    
  
    methods (Access = private)
        
        function results = read_spreadsheet(app)
            %load spreadsheet
            base_folder = fileparts(mfilename('fullpath'));
            spreadsheet_filename = fullfile(base_folder, 'data_MMC.xlsx');
           
            %extract values from spreadsheet 
            [~,app.name] = xlsread(spreadsheet_filename, "User", 'A2:A100');
            [~,app.exp] = xlsread(spreadsheet_filename, "Experiment", 'A2:A100');
            mouseID = xlsread(spreadsheet_filename, "Mouse", 'A2:A100');
            app.mouseID = string(mouseID);
            
            group = xlsread(spreadsheet_filename, "Mouse", 'B2:B100');
            sgroup = string(group);
            
            ndob = xlsread(spreadsheet_filename, "Mouse", 'c2:c100');
            sumdob = sum(ndob,2);
            chardob = datestr(sumdob+datenum('30-Dec-1899'));
            strdob = datetime(chardob,'InputFormat','dd-MMM-yyyy');
            sdob = string(strdob);
            nowt = datetime('now');
            timedate = string(between(strdob,nowt,'months'));
            
            [~,ssex] = xlsread(spreadsheet_filename, "Mouse", 'D2:D100');
            [~,sgenotype] = xlsread(spreadsheet_filename, "Mouse", 'E2:E100');
               
            app.group = sgroup;
            app.dob = sdob;
            app.sex = ssex;
            app.genotype = sgenotype;
            app.age = timedate;
           
            %values to app variables 
            app.UserDropDown.Items = app.name;
            app.ExperimentDropDown.Items = app.exp;
            app.MouseIDDropDown.Items = app.mouseID;
           
            
            %set up dropdown index 
            app.MouseIDDropDown.ItemsData = 1:200;
            
            app.metadata.mouseID =  app.mouseID;
            app.metadata.mouseAge = app.age;
            app.metadata.exp = app.exp;
            app.metadata.group = app.group;
            app.metadata.dob = app.dob;
            app.metadata.sex = app.sex;
            app.metadata.genotype = app.genotype;
            
            app.data.time = [89 ,74, 62, 49, 40, 31];
        end
        
     
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.basefolder = fileparts(mfilename('fullpath'));
            read_spreadsheet(app);
            
            D = fullfile(app.basefolder, 'data');
            if exist (D,'dir')
        
            else
                mkdir(D);
            end
            app.savedir = D;
            app.saveLabel.Text = string(app.savedir);
            app.DateandtimeLabel.Text = datestr(now,'mm-dd-yy HH-MM-SS'); 
            
        end

        % Value changed function: MouseIDDropDown
        function SelectID(app, event)
            value = app.MouseIDDropDown.Value;
          
            app.GroupLabel.Text = 'Group: '+ app.group(value);
            app.DobLabel.Text = 'Date of Birth: '+app.dob(value); 
            app.SexLabel.Text =  append('Sex: ', app.sex(value));
            app.GenotypeLabel.Text = append( 'Genotype: ', app.genotype(value));
            app.AgeLabel.Text =  append('Age: ',app.age(value),'nths');
            
        end

        % Value changed function: RunExperimentButton
        function RunExperiment(app, event)
            value = app.RunExperimentButton.Value;
            D = app.savedir;
            N = datestr(now,'mm-dd-yy HH-MM-SS');
            app.savefile = [fullfile(D,N), '.mat'];
            
            metadata = app.metadata;
            data = app.data;
            
            save(app.savefile,'metadata','data'); 
           
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UserDropDownLabel = uilabel(app.UIFigure);
            app.UserDropDownLabel.Text = 'User';
            app.UserDropDown = uidropdown(app.UIFigure);
            app.ExperimentDropDownLabel = uilabel(app.UIFigure);
            app.ExperimentDropDownLabel.Text = 'Experiment';
            app.ExperimentDropDown = uidropdown(app.UIFigure);
            app.DateandtimeLabel = uilabel(app.UIFigure);
            app.DateandtimeLabel.Text = 'Date and time';
            app.MouseIDDropDownLabel = uilabel(app.UIFigure);
            app.MouseIDDropDownLabel.Text = 'Mouse ID ';
            app.MouseIDDropDown = uidropdown(app.UIFigure);
            app.MouseIDDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectID, true);
            app.DobLabel = uilabel(app.UIFigure);
            app.DobLabel.Text = 'Dob';
            app.SexLabel = uilabel(app.UIFigure);
            app.SexLabel.Text = 'Sex';
            app.GenotypeLabel = uilabel(app.UIFigure);
            app.GenotypeLabel.Text = 'Genotype';
            app.GroupLabel = uilabel(app.UIFigure);
            app.GroupLabel.Text = 'Group';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Experiment name'; 'Date & Time'; 'User'; ''};
            app.UITable.RowName = {};
            app.RunExperimentButton = uibutton(app.UIFigure, 'state');
            app.RunExperimentButton.ValueChangedFcn = createCallbackFcn(app, @RunExperiment, true);
            app.RunExperimentButton.Text = 'Run Experiment';
            app.AgeLabel = uilabel(app.UIFigure);
            app.AgeLabel.Text = 'Age';
            app.saveLabel = uilabel(app.UIFigure);
            app.saveLabel.Text = 'save ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1_copy

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end